classdef DOYDEWf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        D3DVisualizationofInterferencePatternTab  matlab.ui.container.Tab
        ScreenDistanceSliderLabel1  matlab.ui.control.Label
        ScreenDistanceSlider1       matlab.ui.control.Slider
        SlitWidthSliderLabel1       matlab.ui.control.Label
        SlitSeparationSliderLabel1  matlab.ui.control.Label
        WavelengthSliderLabel1l     matlab.ui.control.Label
        WavelengthSlider1           matlab.ui.control.Slider
        SlitSeparationSlider1       matlab.ui.control.Slider
        SimulateButton1             matlab.ui.control.Button
        SlitWidthSlider1            matlab.ui.control.Slider
        UIAxes2D1                   matlab.ui.control.UIAxes
        UIAxes3D1                   matlab.ui.control.UIAxes
        LiveSimulationofDoubleSlitExperimentTab  matlab.ui.container.Tab
        controlsPanel2              matlab.ui.container.Panel
        ScreenDistanceSlider2       matlab.ui.control.Slider
        SlitWidthSlider2            matlab.ui.control.Slider
        SlitSeparationSlider2       matlab.ui.control.Slider
        WavelengthSlider2           matlab.ui.control.Slider
        SlitWidthLabel2             matlab.ui.control.Label
        SlitSeparationLabel2        matlab.ui.control.Label
        WavelengthLabel2            matlab.ui.control.Label
        ScreenDistanceLabel2        matlab.ui.control.Label
        SimulateButton2             matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        DoubleSlitExperimentWaveCalculatorTab  matlab.ui.container.Tab
        ResultLabel3                matlab.ui.control.Label
        CalculateButton3            matlab.ui.control.Button
        SlitEditField3              matlab.ui.control.NumericEditField
        ScreenField3                matlab.ui.control.NumericEditField
        WaveField3                  matlab.ui.control.NumericEditField
        ResultTextArea3             matlab.ui.control.TextArea
        DoubleSlitExperimentWaveCalculatorLabel  matlab.ui.control.Label
        ScreenLabel3                matlab.ui.control.Label
        SlitLabel3                  matlab.ui.control.Label
        WaveLabel3                  matlab.ui.control.Label
        SimulationforElectronsTab   matlab.ui.container.Tab
        ControlsPanel_3             matlab.ui.container.Panel
        NumberOfElectronsField4     matlab.ui.control.NumericEditField
        ScreenDistanceField4        matlab.ui.control.NumericEditField
        SlitSeparationField4        matlab.ui.control.NumericEditField
        NumberOfElectronsLabel4     matlab.ui.control.Label
        ElectronWavelengthField4    matlab.ui.control.NumericEditField
        StartButton4                matlab.ui.control.Button
        ElectronWavelengthLabel4    matlab.ui.control.Label
        ScreenDistanceLabel4        matlab.ui.control.Label
        SlitSeparationLabel4        matlab.ui.control.Label
        PlotAxes4                   matlab.ui.control.UIAxes
        SimulationofintferencepatternformassiveparticlesTab  matlab.ui.container.Tab
        ControlsPanel5              matlab.ui.container.Panel
        V_5                         matlab.ui.control.NumericEditField
        SD5                         matlab.ui.control.NumericEditField
        slitsep5                    matlab.ui.control.NumericEditField
        vlabel5                     matlab.ui.control.Label
        Mass5                       matlab.ui.control.NumericEditField
        Simulate5                   matlab.ui.control.Button
        mlabel5                     matlab.ui.control.Label
        ScreenDLabel5               matlab.ui.control.Label
        SlitSLabel5                 matlab.ui.control.Label
        Axes5                       matlab.ui.control.UIAxes
        InterferencePatternofMassiveParticlesWithNoiseTab  matlab.ui.container.Tab
        ControlsPanel               matlab.ui.container.Panel
        Simulate6                   matlab.ui.control.Button
        NoiseSlider6                matlab.ui.control.Slider
        ScreenDistanceSlider6       matlab.ui.control.Slider
        SlitSeparationSlider6       matlab.ui.control.Slider
        VelocitySlider6             matlab.ui.control.Slider
        MassSlider6                 matlab.ui.control.Slider
        NoiseLabel6                 matlab.ui.control.Label
        ScreenDistanceLabel6        matlab.ui.control.Label
        SlitSeparationLabel6        matlab.ui.control.Label
        VelocityLabel6              matlab.ui.control.Label
        MassLabel6                  matlab.ui.control.Label
        UIAxes6                     matlab.ui.control.UIAxes
        ElectronDoubleSlitExperimentCalculatorTab  matlab.ui.container.Tab
        CalculateButton7            matlab.ui.control.Button
        ResultTextArea7             matlab.ui.control.TextArea
        ScreenDistanceEditField7    matlab.ui.control.NumericEditField
        SlitSeparationEditField7    matlab.ui.control.NumericEditField
        VelocityEditField7          matlab.ui.control.NumericEditField
        SlitSeparationLabel7        matlab.ui.control.Label
        VelocityLabel7              matlab.ui.control.Label
        ResultLabel7                matlab.ui.control.Label
        ScreenDistanceLabel7        matlab.ui.control.Label
        ElectronDoubleSlitExperimentCalculatorLabel  matlab.ui.control.Label
    end

 %% Properties to store parameter values in tab2
    properties (Access = private)
        wavelength2 = 500e-9;                                               % Wavelength of light
        d2 = 1e-4;                                                         % Distance between the slits
        a2 = 1e-5;                                                         % Width of each slit
        screen2 = 1;                                                       % Distance from slits to screen
    end
    %%
 methods (Access = private)
        %% Function for double-slit simulation in tab1
        function Simulate(app)
            d1 = app.SlitSeparationSlider1.Value * 1e-4;                   % d: slit separation in meters
            a1 = app.SlitWidthSlider1.Value * 1e-5;                        % a: slit width in meters
            lambda1 = app.WavelengthSlider1.Value * 1e-9;                  % lambda: wavelength in meters
            L1 = app.ScreenDistanceSlider1.Value;                          % L: screen distance in meters
            
            % Parameters and constants
            num_points = 1000;                                             % Number of points on the screen
            screen1 = linspace(-0.01, 0.01, num_points);                   % Screen positions (in meters)
            k1 = 2 * pi / lambda1;                                         % Wave number
            path_diff1 = d1 * screen1 / L1;                                % Calculate path difference for each point on the screen
            phase_diff1 = k1 * path_diff1;                                 % Calculate phase difference 
            intensity1 = (cos(phase_diff1 / 2)).^2;                        % Calculate intensity (for each slit as a point source)
            
            beta1 = k1 * a1 * screen1 / (2 * L1);                          % Include the effect of slit width (diffraction pattern)
            diffraction_pattern1 = (sin(beta1) ./ beta1).^2;               % Calculate diffraction pattern
            diffraction_pattern1(beta1 == 0) = 1;
            
            total_intensity1 = intensity1 .* diffraction_pattern1;         % Total intensity is the product of interference and diffraction pattern
            total_intensity1 = total_intensity1 / max(total_intensity1);   % Normalize the intensity
            
            % 2D Plot Interference pattern
            plot(app.UIAxes2D1, screen1, total_intensity1, 'LineWidth', 1.5);
             
            % 3D Plot
            [X, Y] = meshgrid(screen1, linspace(0, 1, 100));   
            Z = repmat(total_intensity1, [100, 1]);           
            surf(app.UIAxes3D1, X, Y, Z, 'EdgeColor', 'none');             % Create surface plot
         
            
        end
        
         %% Function for double-slit simulation in tab2
        function updateWavelength(app)
            app.wavelength2 = app.WavelengthSlider2.Value * 1e-9;
        end

        function updateSlitSeparation(app)
            app.d2 = app.SlitSeparationSlider2.Value * 1e-4;
        end
      
        function updateSlitWidth(app)
            app.a2 = app.SlitWidthSlider2.Value * 1e-5;
        end

        function updateScreenDistance(app)
            app.screen2 = app.ScreenDistanceSlider2.Value;
        end
 
        %% Simulate the double-slit experiment in tab2
        function simulateButtonPushed(app)
            % Parameters
            app.wavelength2 = app.wavelength2;                             % Wavelength of light
            app.d2 = app.d2;                                               % Distance between the slits
            app.a2 = app.a2;                                               % Width of each slit
            app.screen2 = app.screen2;                                     % Distance from slits to screen
            num_points2 = 1000;                                            % Number of points on the screen
            num_frames2 = 200;                                             % Number of frames in the video
            time_steps2 = linspace(0, 2*pi, num_frames2);                  % Time steps for the simulation
            screen_x2 = linspace(-0.01, 0.01, num_points2);                % Screen coordinates
            k2 = 2 * pi / app.wavelength2;                                 %wave number

            % Loop over time steps to create the video
            for t = time_steps2 
                path_diff2 = app.d2 * screen_x2 / app.screen2;             % Calculate the path difference for each point on the screen
                phase_diff2= k2 * path_diff2 + t;                          % Calculate the phase difference 
                
                intensity = (cos(phase_diff2 / 2)).^2;                     % Calculate the resultant intensity (foreach slit as source)
                % Include the effect of slit width (diffraction pattern)
                beta2 = k2 * app.a2 * screen_x2 / (2 * app.screen2);
                diff_pattern2 = (sin(beta2) ./ beta2).^2;
                diff_pattern2(beta2 == 0) = 1;
                total_intensity2 = intensity .* diff_pattern2;              % Total intensity is the product of interference and diffraction pattern
                total_intensity2 = total_intensity2 / max(total_intensity2);

                plot(app.UIAxes2, screen_x2, total_intensity2, 'LineWidth', 1.5); % Plot the current frame
                drawnow;
            end
        end    
        %% Button pushed function: SimulateButton in tab 5
        function SimulateButtonPushed5(app, event)
            % Constants
            h = 6.626e-34;                                                 % Planck's constant (m²·kg/s)
           
            mass5 = app.Mass5.Value;                                       % mass of particle in kg
            velocity5 = app.V_5.Value;                                     % velocity in m/s
            d5 = app.slitsep5.Value;                                       % separation in meters
            L5 = app.SD5.Value;                                            % distance from slits to screen in meters
            
            % Calculate de Broglie wavelength
            wavelength = h / (mass5 * velocity5);
            
            % Parameters for the double slit experiment
            L = L5;                                                        % Distance from slits to screen (m)
            d = d5;                                                        % Distance between slits (m)
            x = linspace(-0.02, 0.02, 1000);                               % Screen width (meters)
            
            % Calculate interference pattern
            k5 = 2*pi / wavelength;                                        % wave number
            theta5 = atan(x / L);                                           % angle of interference
             I = (cos(k5 * d * sin(theta5) / 2)).^2;                        % Intensity pattern 

            
            % Plot the interference pattern
            plot(app.Axes5, x, I);
            
        end


        %% Button pushed function: SimulateButton in tab6
        function SimulateButtonPushed6(app, event)
            % Constants
            h = 6.626e-34;                                                 % Planck's constant (m²·kg/s)
            
            % Retrieve values from sliders
            mass6 = app.MassSlider6.Value * 1e-30;                         % Convert from slider range (kg)
            velocity6 = app.VelocitySlider6.Value;                         % velocity in m/s
            slit_separation6 = app.SlitSeparationSlider6.Value;            % separation in meters
            screen_distance6 = app.ScreenDistanceSlider6.Value;            % distance from slits to screen in meters
            noise_level6 = app.NoiseSlider6.Value;                         % level of Gaussian noise
            wavelength6 = h / (mass6 * velocity6);                         % Calculate de Broglie wavelength
            
            % Parameters
            L6 = screen_distance6;                                         % Distance from slits to screen (m)
            d6 = slit_separation6;                                        % Distance between slits (m)
            x6 = linspace(-0.02, 0.02, 1000);                             % Screen width (m)
            
            % Calculate interference pattern
            k6 = 2*pi / wavelength6;                                       % wave number
            theta6 = atan(x6 / L6);                                        % angle of interference
            I6 = (cos(k6 * d6 * sin(theta6) / 2)).^2;                      % Intensity pattern       
            noise6 = noise_level6 * randn(size(I6));                       % Add Gaussian noise
            I_noisy = I6 + noise6;
            
            % Plotting the noisy interference pattern
            plot(app.UIAxes6, x6, I_noisy);

        end

         %% Button pushed function: CalculateButton in tab3
        function CalculateButtonPushed(app, event)
            % Get user inputs
            slit_separationtab4 = app.SlitEditField3.Value *10^-4;
            wavelengthtab4 = app.WaveField3.Value *10^-9;
            screen_distancetab4 = app.ScreenField3.Value;
            % Calculate fringe spacing
            fringe_spacing = wavelengthtab4 * screen_distancetab4 / slit_separationtab4;
            results = sprintf(['Fringe Spacing: %.5e meters\n' ...
                               'Max Intensity: 1\n' ...
                               'Min Intensity: 0\n' ...
                               'Note: Max and Min Intensity are ideal values under perfect conditions.'], fringe_spacing);
            
            app.ResultTextArea3.Value = results;
        end
         
%% Button pushed function: CalculateButton tab 7
        function CalculateButtonPushed90(app, event)
            % Constants
            h = 6.626e-34;                                                % Planck's constant (J.s)
            m_e7 = 9.109e-31;                                             % Mass of electron (kg)
          
            v7 = app.VelocityEditField7.Value;                            % Electron velocity (m/s)
            d7= app.SlitSeparationEditField7.Value * 10^-4;               % Slit separation (m)
            L7 = app.ScreenDistanceEditField7.Value;                      % Screen distance (m)
          
            if v7 <= 0 || d7 <= 0 || L7 <= 0
                app.ResultTextArea7.Value = 'Please enter positive values for all inputs.';
                return;
            end
            
            % Calculate de Broglie wavelength
            lambda7 = h / (m_e7 * v7);
            
            % Calculate fringe spacing
            fringe_spacing7 = (lambda7 * L7) / d7;
            
            % Display results
            results = sprintf(['De Broglie Wavelength: %.5e (m)\n' ...
                               'Fringe Spacing: %.5e (m)\n'], ...
                               lambda7, fringe_spacing7);
            app.ResultTextArea7.Value = results;
        end
        %% Button pushed function: StartButton in tab 4
        function StartButtonPushed(app, event)
            % Get user input values
            numElectrons4 = app.NumberOfElectronsField4.Value;
            d4 = app.SlitSeparationField4.Value;
            L4 = app.ScreenDistanceField4.Value;
            lambda4 = app.ElectronWavelengthField4.Value;                  % Electron's de Broglie wavelength

            cla(app.PlotAxes4);                                            % Clear the plot

            % Screen size and parameters for plotting
            screenWidth4 = 0.02;                                           % Width of the screen in meters (arbitrary value)
            yVals = linspace(-screenWidth4 / 2, screenWidth4 / 2, 1000);   % Screen positions

            % Plot electron hits over time
            for i4 = 1:numElectrons4
                % Random horizontal position for electron (following interference pattern)
                xPos = randn * screenWidth4;                               % Random Gaussian distribution for position

                % Calculate interference intensity based on double-slit conditions
                intensity4 = cos(pi * d4 * xPos / lambda4 / L4)^2;

                % Electron hit occurs based on intensity (simulate the probabilistic nature)
                if rand < intensity4
                    % Plot electron hit on the screen (using scatter for visual effect)
                    scatter(app.PlotAxes4, xPos, i4, 5, 'k', 'filled');
                    hold(app.PlotAxes4, 'on');
                end

                % Update plot dynamically (optional delay for better visualization)
                pause(0.01);
            end
            hold(app.PlotAxes4, 'off');
        end
 end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SimulateButton1
        function SimulateButton1Pushed(app, event)
            Simulate(app);
        end

        % Button pushed function: SimulateButton2
        function SimulateButton2Pushed(app, event)
            app.SimulateButton2.ButtonPushedFcn = @(btn,event) simulateButtonPushed(app);
        end

        % Callback function
        function SimulateButton3Pushed(app, event)
            app.SimulateButton3.ButtonPushedFcn = @(btn,event) simulateButtonPushed3(app);
        end

        % Button pushed function: Simulate5
        function Simulate5Pushed(app, event)
       app.Simulate5.ButtonPushedFcn = @(btn,event) SimulateButtonPushed5(app);
        end

        % Callback function: ControlsPanel5, UIFigure, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: Mass5
        function Mass5ValueChanged(app, event)
            value = app.Mass5.Value;
            
        end

        % Button pushed function: Simulate6
        function Simulate6Pushed(app, event)
            app.Simulate6.ButtonPushedFcn = @(btn,event)SimulateButtonPushed6(app);
        end

        % Button pushed function: CalculateButton3
        function CalculateButton3Pushed(app, event)
app.CalculateButton3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
        end

        % Button pushed function: CalculateButton7
        function CalculateButton7Pushed(app, event)
            app.CalculateButton7.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed90, true);
        end

        % Button pushed function: StartButton4
        function StartButton4Pushed(app, event)
             app.StartButton4.ButtonPushedFcn = @(~, ~)StartButtonPushed(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.D3DVisualizationofInterferencePatternTab = uitab(app.TabGroup);
            app.D3DVisualizationofInterferencePatternTab.Title = '2D , 3D Visualization of Interference Pattern';
            app.UIAxes3D1 = uiaxes(app.D3DVisualizationofInterferencePatternTab);
            title(app.UIAxes3D1, '3D Visualization of Interference Pattern')
            xlabel(app.UIAxes3D1, 'Position on screen (m)')
            ylabel(app.UIAxes3D1, 'Distance')
            zlabel(app.UIAxes3D1, 'Intensity')
            app.UIAxes3D1.XLim = [-0.01 0.01];
            app.UIAxes2D1 = uiaxes(app.D3DVisualizationofInterferencePatternTab);
            title(app.UIAxes2D1, 'Double-Slit Interference Pattern')
            xlabel(app.UIAxes2D1, 'Position on screen (m)')
            ylabel(app.UIAxes2D1, 'Intensity')
            zlabel(app.UIAxes2D1, 'Z')
            app.UIAxes2D1.XLim = [-0.01 0.01];
            app.SlitWidthSlider1 = uislider(app.D3DVisualizationofInterferencePatternTab);
            app.SlitWidthSlider1.Limits = [1 10];
            app.SlitWidthSlider1.Value = 1;
            app.SimulateButton1 = uibutton(app.D3DVisualizationofInterferencePatternTab, 'push');
            app.SimulateButton1.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton1Pushed, true);
            app.SimulateButton1.Text = 'Simulate';
            app.SlitSeparationSlider1 = uislider(app.D3DVisualizationofInterferencePatternTab);
            app.SlitSeparationSlider1.Limits = [1 10];
            app.SlitSeparationSlider1.Value = 1;
            app.WavelengthSlider1 = uislider(app.D3DVisualizationofInterferencePatternTab);
            app.WavelengthSlider1.Limits = [400 700];
            app.WavelengthSlider1.Value = 400;
            app.WavelengthSliderLabel1l = uilabel(app.D3DVisualizationofInterferencePatternTab);
            app.WavelengthSliderLabel1l.Text = 'Wavelength (nm)';
            app.SlitSeparationSliderLabel1 = uilabel(app.D3DVisualizationofInterferencePatternTab);
            app.SlitSeparationSliderLabel1.Text = 'Slit separation *(10^-4)';
            app.SlitWidthSliderLabel1 = uilabel(app.D3DVisualizationofInterferencePatternTab);
            app.SlitWidthSliderLabel1.Text = 'Slit width *(10^-5)';
            app.ScreenDistanceSlider1 = uislider(app.D3DVisualizationofInterferencePatternTab);
            app.ScreenDistanceSlider1.Limits = [0.5 2];
            app.ScreenDistanceSlider1.Value = 0.5;
            app.ScreenDistanceSliderLabel1 = uilabel(app.D3DVisualizationofInterferencePatternTab);
            app.ScreenDistanceSliderLabel1.Text = 'Screen Distance(m)';
            app.LiveSimulationofDoubleSlitExperimentTab = uitab(app.TabGroup);
            app.LiveSimulationofDoubleSlitExperimentTab.Title = 'Live Simulation of Double -Slit Experiment';
            app.UIAxes2 = uiaxes(app.LiveSimulationofDoubleSlitExperimentTab);
            title(app.UIAxes2, 'Live Simulation of Double -Slit Experiment')
            xlabel(app.UIAxes2, 'Position on screen (m)')
            ylabel(app.UIAxes2, 'Intensity')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-0.01 0.01];
            app.controlsPanel2 = uipanel(app.LiveSimulationofDoubleSlitExperimentTab);
            app.controlsPanel2.Title = 'Controls';
            app.SimulateButton2 = uibutton(app.controlsPanel2, 'push');
            app.SimulateButton2.ButtonPushedFcn = createCallbackFcn(app, @SimulateButton2Pushed, true);
            app.SimulateButton2.Text = 'Simulate';
            app.ScreenDistanceLabel2 = uilabel(app.controlsPanel2);
            app.ScreenDistanceLabel2.Text = 'Screen Distance(m)';
            app.WavelengthLabel2 = uilabel(app.controlsPanel2);
            app.WavelengthLabel2.Text = 'Wavelength(nm)';
            app.SlitSeparationLabel2 = uilabel(app.controlsPanel2);
            app.SlitSeparationLabel2.Text = 'Slit separation *(10^-4)';
            app.SlitWidthLabel2 = uilabel(app.controlsPanel2);
            app.SlitWidthLabel2.Text = 'Slit Width *(10^-5)';
            app.WavelengthSlider2 = uislider(app.controlsPanel2);
            app.WavelengthSlider2.Limits = [400 700];
            app.WavelengthSlider2.Value = 400;
            app.SlitSeparationSlider2 = uislider(app.controlsPanel2);
            app.SlitSeparationSlider2.Limits = [1 10];
            app.SlitSeparationSlider2.Value = 1;
            app.SlitWidthSlider2 = uislider(app.controlsPanel2);
            app.SlitWidthSlider2.Limits = [1 10];
            app.SlitWidthSlider2.Value = 1;
            app.ScreenDistanceSlider2 = uislider(app.controlsPanel2);
            app.ScreenDistanceSlider2.Limits = [0.5 2];
            app.ScreenDistanceSlider2.Value = 0.5;
            app.DoubleSlitExperimentWaveCalculatorTab = uitab(app.TabGroup);
            app.DoubleSlitExperimentWaveCalculatorTab.Title = 'Double-Slit Experiment Wave Calculator';
            app.WaveLabel3 = uilabel(app.DoubleSlitExperimentWaveCalculatorTab);
            app.WaveLabel3.Text = 'Wavelength(nm)';
            app.SlitLabel3 = uilabel(app.DoubleSlitExperimentWaveCalculatorTab);
            app.SlitLabel3.Text = 'Slit separation *(10^-4)';
            app.ScreenLabel3 = uilabel(app.DoubleSlitExperimentWaveCalculatorTab);
            app.ScreenLabel3.Text = 'Screen Distance(m)';
            app.DoubleSlitExperimentWaveCalculatorLabel = uilabel(app.DoubleSlitExperimentWaveCalculatorTab);
            app.DoubleSlitExperimentWaveCalculatorLabel.Text = '" Double Slit Experiment Wave Calculator "';
            app.ResultTextArea3 = uitextarea(app.DoubleSlitExperimentWaveCalculatorTab);
            app.WaveField3 = uieditfield(app.DoubleSlitExperimentWaveCalculatorTab, 'numeric');
            app.ScreenField3 = uieditfield(app.DoubleSlitExperimentWaveCalculatorTab, 'numeric');
            app.SlitEditField3 = uieditfield(app.DoubleSlitExperimentWaveCalculatorTab, 'numeric');
            app.CalculateButton3 = uibutton(app.DoubleSlitExperimentWaveCalculatorTab, 'push');
            app.CalculateButton3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton3Pushed, true);
            app.CalculateButton3.Text = 'Calculate';
            app.ResultLabel3 = uilabel(app.DoubleSlitExperimentWaveCalculatorTab);
            app.ResultLabel3.Text = 'Result ';
            app.SimulationforElectronsTab = uitab(app.TabGroup);
            app.SimulationforElectronsTab.Title = 'Simulation for Electrons  ';
            app.PlotAxes4 = uiaxes(app.SimulationforElectronsTab);
            title(app.PlotAxes4, 'Electrons Hit over time')
            xlabel(app.PlotAxes4, 'Position on screen (m)')
            ylabel(app.PlotAxes4, 'Time ')
            zlabel(app.PlotAxes4, 'Z')
            app.PlotAxes4.XLim = [-0.02 0.02];
            app.ControlsPanel_3 = uipanel(app.SimulationforElectronsTab);
            app.ControlsPanel_3.Title = 'Controls';
            app.SlitSeparationLabel4 = uilabel(app.ControlsPanel_3);
            app.SlitSeparationLabel4.Text = 'Slit separation (m)';
            app.ScreenDistanceLabel4 = uilabel(app.ControlsPanel_3);
            app.ScreenDistanceLabel4.Text = 'Screen Distance(m)';
            app.ElectronWavelengthLabel4 = uilabel(app.ControlsPanel_3);
            app.ElectronWavelengthLabel4.Text = 'Electrons Wavelength(m)';
            app.StartButton4 = uibutton(app.ControlsPanel_3, 'push');
            app.StartButton4.ButtonPushedFcn = createCallbackFcn(app, @StartButton4Pushed, true);
            app.StartButton4.Text = 'Start';
            app.ElectronWavelengthField4 = uieditfield(app.ControlsPanel_3, 'numeric');
            app.NumberOfElectronsLabel4 = uilabel(app.ControlsPanel_3);
            app.NumberOfElectronsLabel4.Text = 'Number of electrons';
            app.SlitSeparationField4 = uieditfield(app.ControlsPanel_3, 'numeric');
            app.ScreenDistanceField4 = uieditfield(app.ControlsPanel_3, 'numeric');
            app.NumberOfElectronsField4 = uieditfield(app.ControlsPanel_3, 'numeric');
            app.SimulationofintferencepatternformassiveparticlesTab = uitab(app.TabGroup);
            app.SimulationofintferencepatternformassiveparticlesTab.Title = 'Simulation of intference pattern for massive particles';
            app.Axes5 = uiaxes(app.SimulationofintferencepatternformassiveparticlesTab);
            title(app.Axes5, 'Interference Pattern of Massive Particles')
            xlabel(app.Axes5, 'Position on screen (m)')
            ylabel(app.Axes5, 'Intensity')
            zlabel(app.Axes5, 'Z')
            app.Axes5.XLim = [-0.02 0.02];
            app.ControlsPanel5 = uipanel(app.SimulationofintferencepatternformassiveparticlesTab);
            app.ControlsPanel5.Title = 'Controls';
            app.ControlsPanel5.SizeChangedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SlitSLabel5 = uilabel(app.ControlsPanel5);
            app.SlitSLabel5.Text = 'Slit separation (m)';
            app.ScreenDLabel5 = uilabel(app.ControlsPanel5);
            app.ScreenDLabel5.Text = 'Screen Distance(m)';
            app.mlabel5 = uilabel(app.ControlsPanel5);
            app.mlabel5.Text = 'Electron rest Mass (kg)';
            app.Simulate5 = uibutton(app.ControlsPanel5, 'push');
            app.Simulate5.ButtonPushedFcn = createCallbackFcn(app, @Simulate5Pushed, true);
            app.Simulate5.Text = 'Simulate';
            app.Mass5 = uieditfield(app.ControlsPanel5, 'numeric');
            app.Mass5.ValueChangedFcn = createCallbackFcn(app, @Mass5ValueChanged, true);
            app.Mass5.Value = 9.109e-31;
            app.vlabel5 = uilabel(app.ControlsPanel5);
            app.vlabel5.Text = 'Velocity of the massive particles(m/s)';
            app.slitsep5 = uieditfield(app.ControlsPanel5, 'numeric');
            app.slitsep5.Value = 0.0001;
            app.SD5 = uieditfield(app.ControlsPanel5, 'numeric');
            app.V_5 = uieditfield(app.ControlsPanel5, 'numeric');
            app.V_5.Value = 1000000;
            app.InterferencePatternofMassiveParticlesWithNoiseTab = uitab(app.TabGroup);
            app.InterferencePatternofMassiveParticlesWithNoiseTab.Title = 'Interference Pattern of Massive Particles With Noise';
            app.UIAxes6 = uiaxes(app.InterferencePatternofMassiveParticlesWithNoiseTab);
            title(app.UIAxes6, 'Interference Pattern of Massive Particles With Noise')
            xlabel(app.UIAxes6, 'Position on screen (m)')
            ylabel(app.UIAxes6, 'Intensity')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.XLim = [-0.02 0.02];
            app.ControlsPanel = uipanel(app.InterferencePatternofMassiveParticlesWithNoiseTab);
            app.ControlsPanel.Title = 'Controls';
            app.MassLabel6 = uilabel(app.ControlsPanel);
            app.MassLabel6.Text = 'Mass of Massise particles  (kg)';
            app.VelocityLabel6 = uilabel(app.ControlsPanel);
            app.VelocityLabel6.Text = 'Velocity of the massive particles(m/s)';
            app.SlitSeparationLabel6 = uilabel(app.ControlsPanel);
            app.SlitSeparationLabel6.Text = 'Slit separation (m)';
            app.ScreenDistanceLabel6 = uilabel(app.ControlsPanel);
            app.ScreenDistanceLabel6.Text = 'Screen Distance(m)';
            app.NoiseLabel6 = uilabel(app.ControlsPanel);
            app.NoiseLabel6.Text = 'Nosie Level';
            app.MassSlider6 = uislider(app.ControlsPanel);
            app.MassSlider6.Limits = [1e-30 1e-27];
            app.MassSlider6.Value = 1e-30;
            app.VelocitySlider6 = uislider(app.ControlsPanel);
            app.VelocitySlider6.Limits = [100000 10000000];
            app.VelocitySlider6.Value = 100000;
            app.SlitSeparationSlider6 = uislider(app.ControlsPanel);
            app.SlitSeparationSlider6.Limits = [1e-05 0.01];
            app.SlitSeparationSlider6.Value = 1e-05;
            app.ScreenDistanceSlider6 = uislider(app.ControlsPanel);
            app.ScreenDistanceSlider6.Limits = [0.5 5];
            app.ScreenDistanceSlider6.Value = 0.5;
            app.NoiseSlider6 = uislider(app.ControlsPanel);
            app.NoiseSlider6.Limits = [0 0.5];
            app.Simulate6 = uibutton(app.ControlsPanel, 'push');
            app.Simulate6.ButtonPushedFcn = createCallbackFcn(app, @Simulate6Pushed, true);
            app.Simulate6.Text = 'Simulate';
            app.ElectronDoubleSlitExperimentCalculatorTab = uitab(app.TabGroup);
            app.ElectronDoubleSlitExperimentCalculatorTab.Title = 'Electron Double-Slit Experiment Calculator';
            app.ElectronDoubleSlitExperimentCalculatorLabel = uilabel(app.ElectronDoubleSlitExperimentCalculatorTab);
            app.ElectronDoubleSlitExperimentCalculatorLabel.Text = '"Electron Double-Slit Experiment Calculator"';
            app.ScreenDistanceLabel7 = uilabel(app.ElectronDoubleSlitExperimentCalculatorTab);
            app.ScreenDistanceLabel7.Text = 'Screen Distance(m)';
            app.ResultLabel7 = uilabel(app.ElectronDoubleSlitExperimentCalculatorTab);
            app.ResultLabel7.Text = 'Results';
            app.VelocityLabel7 = uilabel(app.ElectronDoubleSlitExperimentCalculatorTab);
            app.VelocityLabel7.Text = 'Electron Velocity (m/s)';
            app.SlitSeparationLabel7 = uilabel(app.ElectronDoubleSlitExperimentCalculatorTab);
            app.SlitSeparationLabel7.Text = 'Slit separation *(10^-4)';
            app.VelocityEditField7 = uieditfield(app.ElectronDoubleSlitExperimentCalculatorTab, 'numeric');
            app.SlitSeparationEditField7 = uieditfield(app.ElectronDoubleSlitExperimentCalculatorTab, 'numeric');
            app.ScreenDistanceEditField7 = uieditfield(app.ElectronDoubleSlitExperimentCalculatorTab, 'numeric');
            app.ResultTextArea7 = uitextarea(app.ElectronDoubleSlitExperimentCalculatorTab);
            app.CalculateButton7 = uibutton(app.ElectronDoubleSlitExperimentCalculatorTab, 'push');
            app.CalculateButton7.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton7Pushed, true);
            app.CalculateButton7.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DOYDEWf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end